classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        ExponenteEditFieldLabel   matlab.ui.control.Label
        ExponenteEditField        matlab.ui.control.NumericEditField
        EnviarButton              matlab.ui.control.Button
        ConstanteLabel            matlab.ui.control.Label
        RealEditFieldLabel        matlab.ui.control.Label
        RealEditField             matlab.ui.control.NumericEditField
        ImaginarioEditFieldLabel  matlab.ui.control.Label
        ImaginarioEditField       matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            %run f_hacer_matriz_julia.m
            exp = app.ExponenteEditField.Value;
            real = app.RealEditField.Value;
            img = app.ImaginarioEditField.Value;
            app.Label.Text = strcat (int2str(real), " + ", int2str(img), "i");
            const = complex(real, img);
            julia = f_hacer_matriz_julia(1000, const, exp);
            n = figure('visible','off');
            n = imagesc(julia);
            saveas(n, "julia", "jpg");
            app.Image.ImageSource = "julia.jpg";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExponenteEditFieldLabel = uilabel(app.LeftPanel);
            app.ExponenteEditFieldLabel.Text = 'Exponente';
            app.ExponenteEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnviarButton = uibutton(app.LeftPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.ConstanteLabel = uilabel(app.LeftPanel);
            app.ConstanteLabel.Text = 'Constante:';
            app.RealEditFieldLabel = uilabel(app.LeftPanel);
            app.RealEditFieldLabel.Text = 'Real';
            app.RealEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ImaginarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ImaginarioEditFieldLabel.Text = 'Imaginario';
            app.ImaginarioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end